classdef D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RedesNeuronaisGUIUIFigure   matlab.ui.Figure
        UILabel_3                   matlab.ui.control.Label
        UILabel_2                   matlab.ui.control.Label
        UILabel_1                   matlab.ui.control.Label
        dropDown                    matlab.ui.control.DropDown
        QuanrtasredesDropDownLabel  matlab.ui.control.Label
        TestarButton                matlab.ui.control.Button
        Descricao                   matlab.ui.control.Label
        CarregarButton              matlab.ui.control.Button
        TrabalhoPrticodeCRLabel     matlab.ui.control.Label
        RedesNeuronaisLabel         matlab.ui.control.Label
        UIAxes_3                    matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes_1                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Net
        NetNum
        NetOp
        Imagem
        Out
        Target
        Simbolus
        Numeros
        Operadores
    end
    
    methods (Access = private)
        
        function calcularResultado = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disableDefaultInteractivity(app.UIAxes_1);
            cla(app.UIAxes_1);
            app.Simbolus = ["0" "1" "2" "3" "4" "5" "6" "7" "8" "9" "add" "div" "mul" "sub"];
            app.Numeros = ["0" "1" "2" "3" "4" "5" "6" "7" "8" "9"];
            app.Operadores = ["add" "div" "mul" "sub"];
            app.Simbolus = (app.Simbolus).';
            app.Numeros = (app.Numeros).';
            app.Operadores = (app.Operadores).';
        end

        % Button pushed function: CarregarButton
        function CarregarButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            if(path ~= 0)
                load(append(path, "/", file), "net");
                app.Net = net;
            end
            
            switch(app.Net.userdata)
                case "1"
                    app.dropDown.Value = "1";
                    load('../model/BNetwork.mat', 'app.Net');
                case "2"
                    app.dropDown.Value = "2";
                case "Fitting (10 neurónios)"
                    app.TopologiaDropDown.Value = "Fitting (10 neurónios)";
                    load('../model/BNetworkNum.mat', 'app.NetNum');
                    load('../model/BNetworkOp.mat', 'app.NetOp');
            end
            
            app.TestarButton.Enable = 'on';
        end

        % Button pushed function: TestarButton
        function TestarButtonPushed(app, event)
            out = sim(app.Net, app.Imagem);
            [M, i] = max(out);
            app.Descricao.Text = sprintf("A imagem introduzida é considerada um %s com uma precisão de %2.2f%%", upper(app.Figuras(i)), (M * 100));
            app.Out = out;
        end

        % Value changed function: dropDown
        function dropDownValueChanged(app, event)
            %value = app.dropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RedesNeuronaisGUIUIFigure = uifigure('Visible', 'off');
            app.RedesNeuronaisGUIUIFigure.Name = 'Redes Neuronais - GUI';
            app.UIAxes_1 = uiaxes(app.RedesNeuronaisGUIUIFigure);
            app.UIAxes_1.XTick = [];
            app.UIAxes_1.YTick = [];
            app.UIAxes_2 = uiaxes(app.RedesNeuronaisGUIUIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.RedesNeuronaisGUIUIFigure);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.RedesNeuronaisLabel = uilabel(app.RedesNeuronaisGUIUIFigure);
            app.RedesNeuronaisLabel.Text = 'Redes Neuronais';
            app.TrabalhoPrticodeCRLabel = uilabel(app.RedesNeuronaisGUIUIFigure);
            app.TrabalhoPrticodeCRLabel.Text = 'Trabalho Prático de CR';
            app.CarregarButton = uibutton(app.RedesNeuronaisGUIUIFigure, 'push');
            app.CarregarButton.ButtonPushedFcn = createCallbackFcn(app, @CarregarButtonPushed, true);
            app.CarregarButton.Text = 'Carregar';
            app.Descricao = uilabel(app.RedesNeuronaisGUIUIFigure);
            app.Descricao.Text = '';
            app.TestarButton = uibutton(app.RedesNeuronaisGUIUIFigure, 'push');
            app.TestarButton.ButtonPushedFcn = createCallbackFcn(app, @TestarButtonPushed, true);
            app.TestarButton.Enable = 'off';
            app.TestarButton.Text = 'Testar';
            app.QuanrtasredesDropDownLabel = uilabel(app.RedesNeuronaisGUIUIFigure);
            app.QuanrtasredesDropDownLabel.Text = 'Quanrtas redes';
            app.dropDown = uidropdown(app.RedesNeuronaisGUIUIFigure);
            app.dropDown.Items = {'1', '2'};
            app.dropDown.ValueChangedFcn = createCallbackFcn(app, @dropDownValueChanged, true);
            app.dropDown.Placeholder = '1';
            app.dropDown.Value = '1';
            app.UILabel_1 = uilabel(app.RedesNeuronaisGUIUIFigure);
            app.UILabel_1.Text = '';
            app.UILabel_2 = uilabel(app.RedesNeuronaisGUIUIFigure);
            app.UILabel_2.Text = '';
            app.UILabel_3 = uilabel(app.RedesNeuronaisGUIUIFigure);
            app.UILabel_3.Text = '';
            % Show the figure after all components are created
            app.RedesNeuronaisGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = D

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.RedesNeuronaisGUIUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.RedesNeuronaisGUIUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RedesNeuronaisGUIUIFigure)
        end
    end
end